classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        WedgesFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        WedgesFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        ConstraintsFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        ConstraintsFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        StrategyFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        StrategyFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        SelectedWedgesTextAreaLabel  matlab.ui.control.Label
        SelectedWedgesTextArea       matlab.ui.control.TextArea
        ReadFilesButton              matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        WedgeNumbersDropDownLabel    matlab.ui.control.Label
        WedgeNumbersDropDown         matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        wedge_data;
        header;
        XData;
        YData;
        Constraints;
        Strategies;
        % Initialize variables
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fileID = fopen('historicaldata.txt','r');
            header1 = fgetl(fileID);
            
            data = [];
            while ~feof(fileID)
                getdata = fgetl(fileID);
                u = str2num(getdata);
                data = [data; u];
            end
            
            app.XData = data(:,1);
            future = 2018:1:2068;
            app.XData = [app.XData; future'];
            app.YData = data(:,2);
            end_year = app.YData(end);
            
            for i = 1:length(future)
                BAU =(end_year*2^((future(i)-2017)/(50)));
                app.YData = [app.YData; BAU];
            end % end for
            
            plot(app.UIAxes,app.XData,app.YData, '-g');
            app.UIAxes.XLim = [1959, 2068];
            
        end

        % Button pushed function: ReadFilesButton
        function ReadFilesButtonPushed(app, event)
            value1 = app.WedgesFileNameWithoutQuotesEditField.Value;
            % Initialize variables
            % Make Sounds for errors when applicable
            % Use Warndlg for error
            
            try
                if strfind(value1,'wedges')
                    FID = fopen(value1);
                    app.header = fgetl(FID);
                    % If file is correct, open text file and extract first line
                    
                    app.UITable.ColumnName = strsplit(app.header, ';');
                    % Make first line of file the header of the table
                    app.wedge_data = {};
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ';');
                        app.wedge_data = [app.wedge_data ; x];
                    end % end while
                    % Extract each line of the file
                    
                    app.UITable.Data = {};
                    app.UITable.Data = app.wedge_data;
                    % Display the data in the Table
                    
                    dropmenu = app.wedge_data(:, 1);
                    dropmenu = [dropmenu; {'Clear all'}; {' '}];
                    
                    app.WedgeNumbersDropDown.Items = dropmenu;
                    % Display wedge numbers in the Drop Down Menu
                    
                    app.WedgeNumbersDropDown.Value = [' '];
                    
                elseif isempty(value1)
                else
                    warndlg('Incorrect file name entered. File name must include the word ''wedges''.', 'Error');
                    % If the wrong file is selected, display error message
                end % end if
            catch
                warndlg('File not found in MATLAB path', 'Error');
                % If the file is not in the MATLAB path, display the error message
            end % end try
            % ------------------------------------------------------------------------------------------------------------------------------------------------
            value2 = app.ConstraintsFileNameWithoutQuotesEditField.Value;
            data = {};
            % Initialize variables
            
            try
                if strfind(value2,'constraints')
                    % If the correct file is selected, extract each line
                    % May want to use strsplit to extract data
                    FID = fopen(value2);
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ' ');
                        data = [data; x];
                    end % end while
                    app.Constraints = data;
                    % Display the data in the appropriate text area


                elseif isempty(value2)
                else
                    warndlg('Incorrect file name entered. File name must include the word ''constraints''.', 'Error');
                    % If the wrong file is selected, display error message
                end % end if
            catch
                warndlg('File not found in MATLAB path', 'Error');
                % If the file is not in the MATLAB path, display error message
            end % end try
            % ------------------------------------------------------------------------------------------------------------------------------------------------
            value3 = app.StrategyFileNameWithoutQuotesEditField.Value;
            data = {};
            % Initialize variables
            
            try
                if strfind(value3,'strategy')
                    % If the correct file is selected, extract each line
                    % May want to use strsplit to extract data
                    FID = fopen(value3);
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ' ');
                        data = [data; x];
                    end % end while


                    header3 = data(1, :);
                    [r, ~] = size(data);
                    header1 = strsplit(app.header, ';');
                    [r2, ~] = size(app.Constraints);
                    
                    
                    app.SelectedWedgesTextArea.Value = [' '];
                    for i = 2:r
                        index1 = find(contains(header3, 'ID'));
                        index2 = find(contains(header1, 'Strategy'));
                        wedge = [char(data{i, index1}), ' ', char(app.wedge_data{str2num(data{i,index1}), index2})];
                        app.SelectedWedgesTextArea.Value = [app.SelectedWedgesTextArea.Value; wedge];
                    end % end for
                elseif isempty(value3)
                else
                    warndlg('Incorrect file name entered. File name must include the word ''strategy''.', 'Error');
                    % If the wrong file is selected, display error message
                end % end if
            catch
                warndlg('File not found in MATLAB path', 'Error');
                % If the file is not in the MATLAB path, display error message
            end % end try
            
            header1 = strsplit(app.header, ';');
            header4 = app.Constraints(1,:);
            [r2, ~] = size(app.Constraints);
            header3 = data(1, :);
            
            index1 = find(contains(header1, 'Strategy'));
            index3 = find(contains(header1, 'Sector'));
            index4 = find(contains(header4, 'Sector'));
            index5 = find(contains(header4, 'Constraints'));
            index6 = find(contains(header3, 'ID'));
            
            
            for j = 2:r2
                Constraint = strsplit(app.wedge_data{str2num(data{j, index6}, index3)}, ',');
                for k = 1:numel(Constraint)
                    %%%%%%%%%% ERROR %%%%%%%%%%
                    if strcmp(string(Constraint(k)), string(app.Constraints{k, index}))
                        app.Constraints{j, index5} = num2str(str2num(app.Constraints{j, index5})-1);
                    end % end if
                    if str2num(app.Constraints{j, index5}) <= 0
                        warndlg('Too many files of desired sector. Please follow given constraints.');
                    end % end if
                end % end for
            end % end for
            
            wedge = [num2str(value), ' ', char(app.wedge_data{value, index3})];
            app.SelectedWedgesTextArea.Value = [app.SelectedWedgesTextArea.Value; wedge];


        end

        % Value changed function: WedgeNumbersDropDown
        function WedgeNumbersDropDownValueChanged(app, event)
            value1 = app.WedgeNumbersDropDown.Value;
            value = str2num(value1);
            header1 = strsplit(app.header, ';');
            header4 = app.Constraints(1,:);
            [r2, ~] = size(app.Constraints);
            
            if strcmp(value1, 'Clear all') == 0
                index1 = find(contains(header1, 'Strategy'));
                index3 = find(contains(header1, 'Sector'));
                index4 = find(contains(header4, 'Sector'));
                index5 = find(contains(header4, 'Constraints'));
                for j = 2:r2
                    Constraint = strsplit(app.wedge_data{value, index3}, ',');
                    for k = 1:numel(Constraint)
                        if strcmp(string(Constraint(k)), string(app.Constraints{j, index4}))
                            app.Constraints{j, index5} = num2str(str2num(app.Constraints{j, index5})-1);
                        end % end if
                        if str2num(app.Constraints{j, index5}) <= 0
                            warndlg('Too many files of desired sector. Please follow given constraints.');
                        end % end if
                    end % end for
                end % end for
                
                wedge = [num2str(value), ' ', char(app.wedge_data{value, index3})];
                app.SelectedWedgesTextArea.Value = [app.SelectedWedgesTextArea.Value; wedge];
            else
                app.SelectedWedgesTextArea.Value = [' '];
            end % end if
            app.WedgeNumbersDropDown.Value = ' ';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WedgesFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.WedgesFileNameWithoutQuotesEditFieldLabel.Text = 'Wedges File Name (Without Quotes)';
            app.WedgesFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.WedgesFileNameWithoutQuotesEditField.Value = 'wedges.txt';
            app.ConstraintsFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintsFileNameWithoutQuotesEditFieldLabel.Text = 'Constraints File Name (Without Quotes)';
            app.ConstraintsFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.ConstraintsFileNameWithoutQuotesEditField.Value = 'constraints.txt';
            app.StrategyFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.StrategyFileNameWithoutQuotesEditFieldLabel.Text = 'Strategy File Name (Without Quotes)';
            app.StrategyFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.StrategyFileNameWithoutQuotesEditField.Value = 'strategy.txt';
            app.SelectedWedgesTextAreaLabel = uilabel(app.UIFigure);
            app.SelectedWedgesTextAreaLabel.Text = 'Selected Wedges';
            app.SelectedWedgesTextArea = uitextarea(app.UIFigure);
            app.ReadFilesButton = uibutton(app.UIFigure, 'push');
            app.ReadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFilesButtonPushed, true);
            app.ReadFilesButton.Text = 'Read Files';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.WedgeNumbersDropDownLabel = uilabel(app.UIFigure);
            app.WedgeNumbersDropDownLabel.Text = 'Wedge Numbers';
            app.WedgeNumbersDropDown = uidropdown(app.UIFigure);
            app.WedgeNumbersDropDown.Items = {'1', '2', '3', '4'};
            app.WedgeNumbersDropDown.ValueChangedFcn = createCallbackFcn(app, @WedgeNumbersDropDownValueChanged, true);
            app.WedgeNumbersDropDown.Value = '1';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end